classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        GapEditFieldLabel              matlab.ui.control.Label
        GapEditField                   matlab.ui.control.NumericEditField
        MismatchEditFieldLabel         matlab.ui.control.Label
        MismatchEditField              matlab.ui.control.NumericEditField
        MatchEditFieldLabel            matlab.ui.control.Label
        MatchEditField                 matlab.ui.control.NumericEditField
        ChoosesubMatrixButton          matlab.ui.control.Button
        WindowsizeEditFieldLabel       matlab.ui.control.Label
        WindowsizeEditField            matlab.ui.control.NumericEditField
        TresholdEditFieldLabel         matlab.ui.control.Label
        TresholdEditField              matlab.ui.control.NumericEditField
        TabGroup2                      matlab.ui.container.TabGroup
        Sequence1Tab                   matlab.ui.container.Tab
        LoadsequenceButton             matlab.ui.control.StateButton
        ButtonGroup                    matlab.ui.container.ButtonGroup
        Button_4                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        Button_6                       matlab.ui.control.RadioButton
        LoadfromfileButton             matlab.ui.control.StateButton
        DatabasetagEditFieldLabel      matlab.ui.control.Label
        DatabasetagEditField           matlab.ui.control.EditField
        Sequence1EditFieldLabel        matlab.ui.control.Label
        Sequence1EditField             matlab.ui.control.EditField
        ID1EditFieldLabel              matlab.ui.control.Label
        ID1EditField                   matlab.ui.control.EditField
        Sequence2Tab                   matlab.ui.container.Tab
        LoadsequenceButton2            matlab.ui.control.StateButton
        ButtonGroup2                   matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Sequence2Label                 matlab.ui.control.Label
        Sequence2EditField             matlab.ui.control.EditField
        LoadfromfileButton2            matlab.ui.control.StateButton
        DatabasetagEditField_2Label    matlab.ui.control.Label
        DatabasetagEditField2          matlab.ui.control.EditField
        ID2EditFieldLabel              matlab.ui.control.Label
        ID2EditField                   matlab.ui.control.EditField
        Sequence1LampLabel             matlab.ui.control.Label
        Sequence1Lamp                  matlab.ui.control.Lamp
        Sequence2LampLabel             matlab.ui.control.Label
        Sequence2Lamp                  matlab.ui.control.Lamp
        subMatrixLampLabel             matlab.ui.control.Label
        subMatrixLamp                  matlab.ui.control.Lamp
        CalculateButton                matlab.ui.control.Button
        ChooseparametersLabel          matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ChoosesequencesLabel           matlab.ui.control.Label
        SelectsubstitutionmatrixLabel  matlab.ui.control.Label
        DotplotTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        GlobalaligmentTab              matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        LocalaligmentTab               matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileSeq1 % Description
        userSequence1 % Description
        userSequence2 % Description
        fileSeq2 % Description
        seq1Length % Description
        seq2Length % Description
        subMatrix % Description
    end
    
    methods (Access = private)
        
        function fastaContent = fileFasta(~,filename)
            fastaContent = fileread(filename); %Variable assingment +1
        end
        
        function fastaContent = fetchFasta(~,identifier)
            URL = 'https://www.ncbi.nlm.nih.gov/entrez/eutils/efetch.fcgi'; %Variable assingment +1
            fastaContent = urlread(URL,'get',{'db','nucleotide','rettype','fasta','id',identifier});  %Variable assingment +1
        end
        
        function [structure] = parseFasta(~,fastaContent)
            files = strsplit(fastaContent,'>');
            
            field1 = 'id';
            field2 = 'sequence';
            
            for i = 2:length(files)
                lines = strsplit(char(files(i)),'\n');
                
                value1 = lines(1);
                value2 = cell2mat((lines(2:length(lines))));
                s = struct(field1,value1,field2,value2);
                structureLoop(i-1) = struct(s);
            end
            structure = structureLoop
        end
        
        function fastaContent = inputFasta(~,sequence,id)
            fastaContent = ['>', id,...
                newline, sequence];
            
        end
        
        
        function matchMatrix = findMatches(app,seq1,seq2)
            seq1 = char(seq1);
            seq2 = char(seq2);
            m = length(seq1);
            n = length(seq2);
            
            matchMatrix = zeros(m,n);
            
            for k = 1:m
                for p = 1:n
                    if seq1(k) == seq2(p)
                        matchMatrix(k,p) = 1;
                    end
                end
            end
        end


        function filteredMatrix = applyWindowFilter(app,matchMatrix,windowSize,treshold)
            [rows,columns] = size(matchMatrix);
            filteredMatrix = zeros(size(matchMatrix));
            for p = 1:rows
                for k = 1:columns
                    if matchMatrix(p,k) == 1
                        zeroCount = 0;
                        for i = 1:windowSize-1
                            if  ~(p+i < rows && k+i < columns && matchMatrix(p+i,k+i) == 1)
                                zeroCount = zeroCount+1;
                            end
                        end
                        if zeroCount <= treshold
                            filteredMatrix(p,k) =1;
                            for i = 1:windowSize-1
                                filteredMatrix(p+i,k+i) = 1;
                            end
                        end
                    end
                end
            end
        end
        
        function [matrixPath, length, matchCount, gapCount, seq1Array, seq2Array] = createGlobalMatrixPath(app,curRow, curColumn, indexMatrix, matrixCompared, seq1,seq2)
            length = 0;
            matchCount = 0;
            gapCount = 0;
            matrixPath = zeros(size(indexMatrix));
            i = 1;
            
            while (curColumn > 1) || (curRow > 1)
                matrixPath(curRow,curColumn) = 1;
                if(indexMatrix(curRow, curColumn) == 1)
                    if(matrixCompared(curRow-1,curColumn-1) == 1) %bo nie wiem czy to match czy mismatch
                        matchCount = matchCount + 1;
                        seq1Array(i)=seq1(curRow-1);
                        seq2Array(i)=seq2(curColumn-1);
                    end
                    curColumn = curColumn - 1;
                    curRow = curRow - 1;
                elseif(indexMatrix(curRow,curColumn) == 2)
                    seq1Array(i)=seq1(curRow-1);
                    seq2Array(i)='_';
                    curRow = curRow - 1;
                    gapCount = gapCount + 1;
                else
                    seq1Array(i)='_';
                    seq2Array(i)=seq2(curColumn-1);
                    curColumn = curColumn - 1;
                    gapCount = gapCount + 1;
                end
                length = length +1;
                i = i + 1;
            end
        end
        
        function matrix = parseInitMatrix(app,matrix, gap, seq1Length, seq2Length)
            for a = 1:seq1Length
                matrix(a+1,1) = gap*a;
            end
            
            for b = 1:seq2Length
                matrix(1,b+1) = gap*b;
            end
        end
        
        function [matrixPath, length, matchCount, gapCount, seq1Array, seq2Array] = createMatrixPath(app,curRow, curColumn, indexMatrix, matrixCompared, seq1,seq2)
            length = 0;
            matchCount = 0;
            gapCount = 0;
            matrixPath = zeros(size(indexMatrix));
            i = 1;
            
            while (curColumn > 1) || (curRow > 1)
                matrixPath(curRow,curColumn) = 1;
                if(indexMatrix(curRow, curColumn) == 1)
                    if(matrixCompared(curRow-1,curColumn-1) == 1)
                        matchCount = matchCount + 1;
                        seq1Array(i)=seq1(curRow-1);
                        seq2Array(i)=seq2(curColumn-1);
                    end
                    curColumn = curColumn - 1;
                    curRow = curRow - 1;
                elseif(indexMatrix(curRow,curColumn) == 2)
                    seq1Array(i)=seq1(curRow-1);
                    seq2Array(i)='_';
                    curRow = curRow - 1;
                    gapCount = gapCount + 1;
                elseif(indexMatrix(curRow,curColumn) == 3)
                    seq1Array(i)='_';
                    seq2Array(i)=seq2(curColumn-1);
                    curColumn = curColumn - 1;
                    gapCount = gapCount + 1;
                end
                length = length +1;
                i = i + 1;
            end
        end
        
        function matrix = mergeMatrices(app,matrix, matrixCompared, seq1Length, seq2Length)
            for k = 1:seq1Length
                for p = 1:seq2Length                        %For loop
                    matrix(k+1,p+1) = matrixCompared(k,p);     %Checking condition +1
                end
            end
        end
        
        
        function [scoredMatrix, indexMatrix] = scoreMatrix(app,scoredMatrix, match, mismatch, gap, m, n)
            indexMatrix = zeros(size(scoredMatrix));
            for k = 1:m%wiersze
                for p = 1:n%kolumny
                    if (scoredMatrix(k+1,p+1) == 1)
                        val1 = scoredMatrix(k,p) + match;
                    else
                        val1 = scoredMatrix(k,p) + mismatch;
                    end
                    valGap1 = scoredMatrix(k,p+1) + gap; %one up
                    valGap2 = scoredMatrix(k+1,p) + gap; %one left
                    [maxValue,index] = max([val1 valGap1 valGap2]);
                    scoredMatrix(k+1,p+1) = maxValue;
                    %if 1 - match/mismatch
                    %if 2 - gap up
                    %if 3 - gap left
                    indexMatrix(k+1,p+1) = index;
                end
            end
        end
        
        function point = findMatch(app,substitutionMatrix,nuclOne,nuclTwo)
            vecRow = substitutionMatrix(1,:);
            vecCol = substitutionMatrix(:,1);
            
            x = char(vecRow) == nuclOne;
            y = char(vecCol) == nuclTwo;
            
            point = str2double(char(substitutionMatrix(x,y)));
            
        end


        function [XCor,YCor] = findMaxCoordinates(app,scoredMatrix)
            maxValue = max(scoredMatrix(:));
            
            XCorLength = length(scoredMatrix(:,1)); %substitution +1
            YCorLength = length(scoredMatrix(1,:)); %substitution +1
            
            XCor = []; %substitution +1
            YCor = []; %substitution +1
            
            %find occurances of the max value
            for m = 1:XCorLength %incrementation, checking condition, n*x; 2 + n*x
                for n = 1:YCorLength %incrementation, checking condition, m*x; 2 + m*x
                    if scoredMatrix(m,n) == maxValue %checking condition +1
                        XCor(end+1) = m; %substitution +1
                        YCor(end+1) = n; %substitution +1
                    end
                end
            end
        end


        function [matrixPath,sequenceObject,matchCount,gapCount] = findLocalPath(app,scoredMatrix,indexMatrix,XCor,YCor,seq1,seq2)
            matrixPath = zeros(length(scoredMatrix(:,1)),length(scoredMatrix(1,:))); %substitution +1
            index = 1; %substitution +1
            sequenceObject = struct;
            while index <= length(XCor) %nie ma znaczenia czy X czy Y
                curRow = XCor(index); %substitution +1
                curCol = YCor(index); %substitution +1
                curIndex = indexMatrix(curRow, curCol); %substitution +1
                scoreIndex = scoredMatrix(curRow, curCol);
                charArray1 = ''; %substitution +1
                charArray2 = ''; %substitution +1
                matchCount = 0;
                gapCount = 0;
                
                while (curIndex ~= 4) && (curIndex ~= 0) && (scoreIndex ~= 0)
                    matrixPath(curRow, curCol) = 1; %substitution +1
                    curIndex = indexMatrix(curRow, curCol); %substitution +1
                    scoreIndex = scoredMatrix(curRow, curCol);
                    
                    if(curIndex == 1) %checking condition + 1
                        charArray1 = strcat(charArray1, seq1(curRow-1)); %substitution +1
                        charArray2 = strcat(charArray2, seq2(curCol-1)); %substitution +1
                        if(seq1(curRow-1) == seq2(curCol-1))
                            matchCount = matchCount + 1;
                        end
                        curRow = curRow - 1; %substitution +1
                        curCol = curCol - 1; %substitution +1
                    elseif(curIndex == 2) %checking condition
                        charArray1 = strcat(charArray1, seq1(curRow-1)); %substitution +1
                        charArray2 = strcat(charArray2, '_'); %substitution +1
                        %gap up
                        curRow = curRow - 1; %substitution +1
                        gapCount = gapCount + 1;
                    elseif(curIndex == 3) %checking condition +1
                        charArray1 = strcat(charArray1, '_'); %substitution +1
                        charArray2 = strcat(charArray2, seq2(curCol-1)); %substitution +1
                        %gap left
                        curCol = curCol -1; %substitution +1
                        gapCount = gapCount + 1;
                    end
                end
                index = index + 1; %substitution +1
                charArray1 = strcat(charArray1, seq1(curRow)); %substitution +1
                charArray2 = strcat(charArray2, seq2(curCol)); %substitution +1
                sequenceObject(index-1).seq1 = flip(charArray1(1:end-1)); %substitution +1
                sequenceObject(index-1).seq2 = flip(charArray2(1:end-1)); %substitution +1
                sequenceObject(index-1).matchCount = matchCount;
                sequenceObject(index-1).gapCount = gapCount;
            end
        end
        
        function [outputSeq,indexMatrix] = smithWaterman(app,gap,seq1,seq2)
            seq1 = strcat('-',seq1);
            seq2 = strcat('-',seq2);
            
            length1 = length(seq1) %substitution +1
            length2 = length(seq2) %substitution +1
            
            outputSeq = zeros(length1,length2); %substitution +1
            indexMatrix = zeros(length1,length2); %substitution +1
            
            substitutionMatrix = app.subMatrix; %substitution +1
            
            for m = 2:length(seq1) %incrementation, checking condition, n*x; 2 + n*x
                for n = 2:length(seq2) %incrementation, checking condition, m*x; 2 + m*x
                    if (seq1(m) == seq2(n)) %checking condition +1
                        value = findMatch(app,substitutionMatrix,seq1(m),seq2(n)) + outputSeq(m-1,n-1); %zwraca punkt z txt; substitution +1
                    else %is mismatch
                        value = findMatch(app,substitutionMatrix,seq1(m),seq2(n)) + outputSeq(m-1,n-1); %substitution +1
                    end
                    
                    ins = findMatch(app,substitutionMatrix,seq1(m),seq2(n-1)) + gap; %substitution +1
                    del = findMatch(app,substitutionMatrix,seq1(m-1),seq2(n)) + gap; %substitution +1
                    
                    [maxVal,index] = max([value ins del 0]); %substitution +2 ?
                    outputSeq(m,n) = maxVal; %substitution +1
                    indexMatrix(m,n) = index; %substitution +1
                    %if 1 - match/mismatch
                    %if 2 - gap up
                    %if 3 - gap left
                    %if 4 - 0
                end
            end
        end
        
    end
    

    methods (Access = private)

        % Selection changed function: ButtonGroup2
        function ButtonGroup2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            switch selectedButton
                case app.Button
                    set(app.LoadfromfileButton2,'Enable','off');
                    set(app.DatabasetagEditField2, 'Enable','off');
                    set(app.Sequence2EditField, 'Enable','on');
                    set(app.ID2EditField, 'Enable','on');
                case app.Button_2
                    set(app.LoadfromfileButton2,'Enable','on');
                    set(app.DatabasetagEditField2, 'Enable','off');
                    set(app.Sequence2EditField, 'Enable','off');
                    set(app.ID2EditField, 'Enable','off');
                case app.Button_3
                    set(app.LoadfromfileButton2,'Enable','off');
                    set(app.DatabasetagEditField2, 'Enable','on');
                    set(app.Sequence2EditField, 'Enable','off');
                    set(app.ID2EditField, 'Enable','off');
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case app.Button_4
                    set(app.LoadfromfileButton,'Enable','off');
                    set(app.DatabasetagEditField, 'Enable','off');
                    set(app.Sequence1EditField, 'Enable','on');
                    set(app.ID1EditField, 'Enable','on');
                case app.Button_5
                    set(app.LoadfromfileButton,'Enable','on');
                    set(app.DatabasetagEditField, 'Enable','off');
                    set(app.Sequence1EditField, 'Enable','off');
                    set(app.ID1EditField, 'Enable','off');
                case app.Button_6
                    set(app.LoadfromfileButton,'Enable','off');
                    set(app.DatabasetagEditField, 'Enable','on');
                    set(app.Sequence1EditField, 'Enable','off');
                    set(app.ID1EditField, 'Enable','off');
            end
        end

        % Button pushed function: ChoosesubMatrixButton
        function ChoosesubMatrixButtonPushed(app, event)
            subMatrix = uigetfile('*.txt');
            
            if isequal(subMatrix,0)
                disp('User selected Cancel');
            else
                subMatrix = textscan(subMatrix,'%s%s%s%s%s');
                subMatrix = [subMatrix{1,1},subMatrix{1,2},subMatrix{1,3},subMatrix{1,4},subMatrix{1,5}]
                
                app.subMatrixLamp.Color = 'g';
                app.subMatrix = subMatrix;
            end
        end

        % Value changed function: LoadsequenceButton
        function LoadsequenceButtonValueChanged(app, event)
            value = app.LoadsequenceButton.Value;
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case app.Button_4 %from txt
                    textSeq1 = app.Sequence1EditField.Value;
                    idSeq1 = app.ID1EditField.Value;
                    if(~isempty(textSeq1) && ~isempty(idSeq1))
                        fastaContent = inputFasta(app,upper(textSeq1),idSeq1);
                        app.userSequence1 = parseFasta(app,fastaContent);
                        app.Sequence1Lamp.Color = 'g';
                    end
                case app.Button_5 %from file
                    if(~isempty(app.fileSeq1))
                        app.userSequence1 = parseFasta(app,app.fileSeq1);
                        app.Sequence1Lamp.Color = 'g';
                    end
                case app.Button_6 %from tag
                    if(~isempty(app.DatabasetagEditField.Value))
                        fetchedFasta = fetchFasta(app,app.DatabasetagEditField.Value);
                        app.userSequence1 = parseFasta(app,fetchedFasta);
                        app.Sequence1Lamp.Color = 'g';
                    end
            end
        end

        % Value changed function: LoadsequenceButton2
        function LoadsequenceButton2ValueChanged(app, event)
            value = app.LoadsequenceButton2.Value;
            selectedButton = app.ButtonGroup2.SelectedObject;
            switch selectedButton
                case app.Button %from txt
                    textSeq2 = app.Sequence2EditField.Value;
                    idSeq2 = app.ID2EditField.Value;
                    if(~isempty(textSeq2) && ~isempty(idSeq2))
                        fastaContent = inputFasta(app,upper(textSeq2),idSeq2);
                        app.userSequence2 = parseFasta(app,fastaContent);
                        app.Sequence2Lamp.Color = 'g';
                    end
                case app.Button_2 %from file
                    if(~isempty(app.fileSeq2))
                        app.userSequence2 = parseFasta(app,app.fileSeq2);
                        app.Sequence2Lamp.Color = 'g';
                    end
                case app.Button_3 %from tag
                    if(~isempty(app.DatabasetagEditField2.Value))
                        app.userSequence2 = parseFasta(app,fetchFasta(app,app.DatabasetagEditField2.Value));
                        app.Sequence2Lamp.Color = 'g';
                    end
            end
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            match = app.MatchEditField.Value;
            mismatch = app.MismatchEditField.Value;
            gap = app.GapEditField.Value;
            windowSize = app.WindowsizeEditField.Value;
            treshold = app.TresholdEditField.Value;
            if(windowSize > 0 && ~isempty(app.userSequence1) && ~isempty(app.userSequence2) && ~isempty(app.subMatrix))
                matchMatrix = findMatches(app,app.userSequence1.sequence, app.userSequence2.sequence);
                seq1Length = length(app.userSequence1.sequence);
                seq2Length = length(app.userSequence2.sequence);
                
                % DOTPLOT
                filteredMatrix = applyWindowFilter(app,matchMatrix,windowSize,treshold);
                %Input data
                figure;
                subplot(1,2,1);
                spy(matchMatrix,'.',4)
                xlabel(app.userSequence1.id);
                ylabel(app.userSequence2.id);
                title('Dotplot from given input data');
                %Wg dokumentacji mam niewspieraną przez nowe funkcję wersję matlaba.
                %Konkretnie chodzi o funkcje, która pozwala na 'normalne' wyświetlanie innych niż plot elementów w aplikacji
                %https://www.mathworks.com/help/matlab/creating_guis/graphics-support-in-app-designer.html
                %Dlatego też, żeby to obejść musiałem wyświetlić zapisane zdjęcie, zamiast 'figure'
                
                %Filtered data
                subplot(1,2,2);
                spy(filteredMatrix,'.',4)
                title('Filtered dotplot from given input data');
                xlabel(app.userSequence1.id);
                ylabel(app.userSequence2.id);
                saveas(gcf,'DotPlot.jpg');
                imshow('DotPlot.jpg','Parent',app.UIAxes);
                
                % GLOBAL
                initMatrix = zeros(seq1Length+1,seq2Length+1); %same zeros
                initMatrix = parseInitMatrix(app,initMatrix,gap,seq1Length,seq2Length); %matrix z pierwszymy row i column obliczonymi
                
                matricesMerged = mergeMatrices(app,initMatrix, matchMatrix, seq1Length, seq2Length);
                [scoredMatrix,indexMatrix] = scoreMatrix(app,matricesMerged, match, mismatch, gap, seq1Length, seq2Length);
                
                [matrixPath,pathLength,matchCount,gapCount,seqMatrix1,seqMatrix2] = createGlobalMatrixPath(app,seq1Length+1, seq2Length+1, indexMatrix, matchMatrix, app.userSequence1.sequence, app.userSequence2.sequence);
                matrixPath(1,1) = 1; %hardcoded, ale konieczne
                
                seqMatrix1 = flip(regexprep(seqMatrix1(~isspace(seqMatrix1)),'\n+',''));
                seqMatrix2 = flip(regexprep(seqMatrix2(~isspace(seqMatrix2)),'\n+',''));
                
                figure;
                subplot(1,2,1)
                heatmap(matrixPath,'XLabel',app.userSequence1.id,'YLabel',app.userSequence2.id,'GridVisible','off','ColorbarVisible','off','ColorScaling','scaledcolumns')
                subplot(1,2,2)
                heatmap(scoredMatrix,'XLabel',app.userSequence1.id,'YLabel',app.userSequence2.id,'GridVisible','off')
                saveas(gcf,'GlobalHeatmaps.jpg');
                imshow('GlobalHeatmaps.jpg','Parent',app.UIAxes2);
                
                fid = fopen('GlobalOutput.txt','wt');
                fprintf(fid,'%s\n', "Sequence 1: " + app.userSequence1.id);
                fprintf(fid,'%s\n', "Sequence 2: " + app.userSequence2.id);
                fprintf(fid,'%s\n', "Gap: " + gap);
                fprintf(fid,'%s\n', "Match: " + match);
                fprintf(fid,'%s\n', "Length: " + (pathLength+1));
                fprintf(fid,'%s\n', "Match count: " + (matchCount+1));
                fprintf(fid,'%s\n', "Gap count: " + gapCount);
                fprintf(fid,'%s\n', "Identity: " + matchCount + "/" + (pathLength+1) + "(" + round(matchCount*100/(pathLength+1)) + ")");
                fprintf(fid,'%s\n', "Gaps: " + gapCount + "/" + (pathLength+1) + "(" + round(gapCount*100/(pathLength+1)) + ")");
                fprintf(fid,'%s\n', seqMatrix1);
                fprintf(fid,'%s\n', seqMatrix2);
                fclose(fid);
                
                %LOCAL
                
                [scoredMatrix, indexMatrix] = smithWaterman(app,gap,app.userSequence1.sequence,app.userSequence2.sequence);
                
                %find max value
                [XCor, YCor] = findMaxCoordinates(app,scoredMatrix);
                
                %find a path
                [matrixPath,sequenceObject,matchCount, gapCount] = findLocalPath(app,scoredMatrix,indexMatrix,XCor,YCor,app.userSequence1.sequence,app.userSequence2.sequence);
                
                
                figure;
                subplot(1,2,1)
                heatmap(matrixPath,'XLabel',app.userSequence1.id,'YLabel',app.userSequence2.id,'GridVisible','off','ColorbarVisible','off')
                subplot(1,2,2)
                heatmap(scoredMatrix,'XLabel',app.userSequence1.id,'YLabel',app.userSequence2.id,'GridVisible','off')
                saveas(gcf,'LocalHeatmaps.jpg');
                imshow('LocalHeatmaps.jpg','Parent',app.UIAxes3);
                
                fid = fopen('LocalOutput.txt','wt');
                fprintf(fid,'%s\n', "Gap: " + gap);
                fprintf(fid,'%s\n', "ID: " + app.userSequence1.id);
                fprintf(fid,'%s\n', app.userSequence1.sequence);
                fprintf(fid,'%s\n', "ID: " + app.userSequence2.id);
                fprintf(fid,'%s\n', app.userSequence2.sequence); %parametry programu I guess?
                [x,y] = size(sequenceObject);
                for i = 1:y
                    fprintf(fid,'%s\n','');
                    fprintf(fid,'%s\n','***');
                    fprintf(fid,'%s\n','');
                    fprintf(fid,'%s\n',sequenceObject(i).seq1);
                    fprintf(fid,'%s\n',sequenceObject(i).seq2);
                    fprintf(fid,'%s\n','');
                    identity = 100*(sequenceObject(i).matchCount)./(length(sequenceObject(i).seq1));
                    fprintf(fid,'%s\n',"Identity: " + num2str(identity));
                    fprintf(fid,'%s\n',"Gap count: " + num2str(sequenceObject(i).gapCount));
                end
                fclose(fid);
                
            else
                app.TextArea.Value = "You haven't loaded all the parameters!";
            end
        end

        % Value changed function: LoadfromfileButton
        function LoadfromfileButtonValueChanged(app, event)
            value = app.LoadfromfileButton.Value;
            app.fileSeq1 = uigetfile('*.fasta');
            if isequal(app.fileSeq1,0)
                disp('User selected Cancel');
            else
                app.userSequence1 = parseFasta(app,fileread(app.fileSeq1));
                app.Sequence1Lamp.Color = 'g';
            end
            
        end

        % Value changed function: LoadfromfileButton2
        function LoadfromfileButton2ValueChanged(app, event)
            value = app.LoadfromfileButton2.Value;
            app.fileSeq2 = uigetfile('*.fasta');
            if isequal(app.fileSeq2,0)
                disp('User selected Cancel');
            else
                app.userSequence2 = parseFasta(app,fileread(app.fileSeq2));
                app.Sequence2Lamp.Color = 'g';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GapEditFieldLabel = uilabel(app.ParametersTab);
            app.GapEditFieldLabel.Text = 'Gap';
            app.GapEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MismatchEditFieldLabel = uilabel(app.ParametersTab);
            app.MismatchEditFieldLabel.Text = 'Mismatch';
            app.MismatchEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MatchEditFieldLabel = uilabel(app.ParametersTab);
            app.MatchEditFieldLabel.Text = 'Match';
            app.MatchEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ChoosesubMatrixButton = uibutton(app.ParametersTab, 'push');
            app.ChoosesubMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosesubMatrixButtonPushed, true);
            app.ChoosesubMatrixButton.Text = 'Choose subMatrix';
            app.WindowsizeEditFieldLabel = uilabel(app.ParametersTab);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TresholdEditFieldLabel = uilabel(app.ParametersTab);
            app.TresholdEditFieldLabel.Text = 'Treshold';
            app.TresholdEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TabGroup2 = uitabgroup(app.ParametersTab);
            app.Sequence1Tab = uitab(app.TabGroup2);
            app.Sequence1Tab.Title = 'Sequence 1';
            app.LoadsequenceButton = uibutton(app.Sequence1Tab, 'state');
            app.LoadsequenceButton.ValueChangedFcn = createCallbackFcn(app, @LoadsequenceButtonValueChanged, true);
            app.LoadsequenceButton.Text = 'Load sequence';
            app.ButtonGroup = uibuttongroup(app.Sequence1Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '';
            app.LoadfromfileButton = uibutton(app.ButtonGroup, 'state');
            app.LoadfromfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadfromfileButtonValueChanged, true);
            app.LoadfromfileButton.Enable = 'off';
            app.LoadfromfileButton.Text = 'Load from file';
            app.DatabasetagEditFieldLabel = uilabel(app.ButtonGroup);
            app.DatabasetagEditFieldLabel.Text = 'Database tag';
            app.DatabasetagEditField = uieditfield(app.ButtonGroup, 'text');
            app.DatabasetagEditField.Enable = 'off';
            app.Sequence1EditFieldLabel = uilabel(app.ButtonGroup);
            app.Sequence1EditFieldLabel.Text = 'Sequence 1';
            app.Sequence1EditField = uieditfield(app.ButtonGroup, 'text');
            app.ID1EditFieldLabel = uilabel(app.ButtonGroup);
            app.ID1EditFieldLabel.Text = 'ID 1';
            app.ID1EditField = uieditfield(app.ButtonGroup, 'text');
            app.Sequence2Tab = uitab(app.TabGroup2);
            app.Sequence2Tab.Title = 'Sequence 2';
            app.LoadsequenceButton2 = uibutton(app.Sequence2Tab, 'state');
            app.LoadsequenceButton2.ValueChangedFcn = createCallbackFcn(app, @LoadsequenceButton2ValueChanged, true);
            app.LoadsequenceButton2.Text = 'Load sequence';
            app.ButtonGroup2 = uibuttongroup(app.Sequence2Tab);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup2);
            app.Button.Text = '';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup2);
            app.Button_2.Text = '';
            app.Button_3 = uiradiobutton(app.ButtonGroup2);
            app.Button_3.Text = '';
            app.Sequence2Label = uilabel(app.ButtonGroup2);
            app.Sequence2Label.Text = 'Sequence 2';
            app.Sequence2EditField = uieditfield(app.ButtonGroup2, 'text');
            app.LoadfromfileButton2 = uibutton(app.ButtonGroup2, 'state');
            app.LoadfromfileButton2.ValueChangedFcn = createCallbackFcn(app, @LoadfromfileButton2ValueChanged, true);
            app.LoadfromfileButton2.Enable = 'off';
            app.LoadfromfileButton2.Text = 'Load from file';
            app.DatabasetagEditField_2Label = uilabel(app.ButtonGroup2);
            app.DatabasetagEditField_2Label.Text = 'Database tag';
            app.DatabasetagEditField2 = uieditfield(app.ButtonGroup2, 'text');
            app.DatabasetagEditField2.Enable = 'off';
            app.ID2EditFieldLabel = uilabel(app.ButtonGroup2);
            app.ID2EditFieldLabel.Text = 'ID 2';
            app.ID2EditField = uieditfield(app.ButtonGroup2, 'text');
            app.Sequence1LampLabel = uilabel(app.ParametersTab);
            app.Sequence1LampLabel.Text = 'Sequence 1';
            app.Sequence1Lamp = uilamp(app.ParametersTab);
            app.Sequence2LampLabel = uilabel(app.ParametersTab);
            app.Sequence2LampLabel.Text = 'Sequence 2';
            app.Sequence2Lamp = uilamp(app.ParametersTab);
            app.subMatrixLampLabel = uilabel(app.ParametersTab);
            app.subMatrixLampLabel.Text = 'subMatrix';
            app.subMatrixLamp = uilamp(app.ParametersTab);
            app.CalculateButton = uibutton(app.ParametersTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ChooseparametersLabel = uilabel(app.ParametersTab);
            app.ChooseparametersLabel.Text = 'Choose parameters:';
            app.TextArea = uitextarea(app.ParametersTab);
            app.TextArea.Editable = 'off';
            app.ChoosesequencesLabel = uilabel(app.ParametersTab);
            app.ChoosesequencesLabel.Text = 'Choose sequences:';
            app.SelectsubstitutionmatrixLabel = uilabel(app.ParametersTab);
            app.SelectsubstitutionmatrixLabel.Text = 'Select substitution matrix:';
            app.DotplotTab = uitab(app.TabGroup);
            app.DotplotTab.Title = 'Dotplot';
            app.UIAxes = uiaxes(app.DotplotTab);
            title(app.UIAxes, 'Title')
            app.GlobalaligmentTab = uitab(app.TabGroup);
            app.GlobalaligmentTab.Title = 'Global aligment';
            app.UIAxes2 = uiaxes(app.GlobalaligmentTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.LocalaligmentTab = uitab(app.TabGroup);
            app.LocalaligmentTab.Title = 'Local aligment';
            app.UIAxes3 = uiaxes(app.LocalaligmentTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end